classdef arm_6dof < matlab.apps.AppBase

    % Properties that correspond to app components
    properties (Access = public)
        UIFigure      matlab.ui.Figure
        HomeButton    matlab.ui.control.StateButton
        zSlider       matlab.ui.control.Slider
        zSliderLabel  matlab.ui.control.Label
        ySlider       matlab.ui.control.Slider
        ySliderLabel  matlab.ui.control.Label
        xSlider       matlab.ui.control.Slider
        xSliderLabel  matlab.ui.control.Label
        UIAxes        matlab.ui.control.UIAxes
    end

    
    properties (Access = private)
            x_position = 0;
            y_position = 0;
            z_position = 0;
    end
    

    % Callbacks that handle component events
    methods (Access = private)

        % Code that executes after component creation
        function startupFcn(app)
            plot3(app.UIAxes, app.x_position, app.y_position, app.z_position, 'ro')
            xlim(app.UIAxes, [0 100])
            ylim(app.UIAxes, [0 100])
            zlim(app.UIAxes, [0 100])
        end

        % Value changed function: HomeButton
        function HomeButtonValueChanged(app, event)
            value = app.HomeButton.Value;
            app.x_position = 0;
            app.y_position = 0;
            app.z_position = 0;
        end

        % Value changing function: xSlider
        function xSliderValueChanging(app, event)
            changingValue = event.Value;
            app.x_position = changingValue;
            plot3(app.UIAxes, app.x_position, app.y_position, app.z_position, 'ro')
            xlim(app.UIAxes, [0 100])
            ylim(app.UIAxes, [0 100])
            zlim(app.UIAxes, [0 100])
        end

        % Value changing function: ySlider
        function ySliderValueChanging(app, event)
            changingValue = event.Value;
            app.y_position = changingValue;
            plot3(app.UIAxes, app.x_position, app.y_position, app.z_position, 'ro')
            xlim(app.UIAxes, [0 100])
            ylim(app.UIAxes, [0 100])
            zlim(app.UIAxes, [0 100])
        end

        % Value changing function: zSlider
        function zSliderValueChanging(app, event)
            changingValue = event.Value;
            app.z_position = changingValue;
            plot3(app.UIAxes, app.x_position, app.y_position, app.z_position, 'ro')
            xlim(app.UIAxes, [0 100])
            ylim(app.UIAxes, [0 100])
            zlim(app.UIAxes, [0 100])
        end
    end

    % Component initialization
    methods (Access = private)

        % Create UIFigure and components
        function createComponents(app)
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            app.UIFigure = uifigure('Visible', 'off');
            app.UIFigure.Name = 'MATLAB App';
            app.UIAxes = uiaxes(app.UIFigure);
            title(app.UIAxes, 'polozenie w przestrzeni')
            xlabel(app.UIAxes, 'X')
            ylabel(app.UIAxes, 'Y')
            zlabel(app.UIAxes, 'Z')
            app.xSliderLabel = uilabel(app.UIFigure);
            app.xSliderLabel.Text = 'x';
            app.xSlider = uislider(app.UIFigure);
            app.xSlider.ValueChangingFcn = createCallbackFcn(app, @xSliderValueChanging, true);
            app.ySliderLabel = uilabel(app.UIFigure);
            app.ySliderLabel.Text = 'y';
            app.ySlider = uislider(app.UIFigure);
            app.ySlider.ValueChangingFcn = createCallbackFcn(app, @ySliderValueChanging, true);
            app.zSliderLabel = uilabel(app.UIFigure);
            app.zSliderLabel.Text = 'z';
            app.zSlider = uislider(app.UIFigure);
            app.zSlider.ValueChangingFcn = createCallbackFcn(app, @zSliderValueChanging, true);
            app.HomeButton = uibutton(app.UIFigure, 'state');
            app.HomeButton.ValueChangedFcn = createCallbackFcn(app, @HomeButtonValueChanged, true);
            app.HomeButton.Text = 'Home';
            % Show the figure after all components are created
            app.UIFigure.Visible = 'on';
        end
    end

    % App creation and deletion
    methods (Access = public)

        % Construct app
        function app = arm_6dof

            % Create UIFigure and components
            createComponents(app)

            % Register the app with App Designer
            registerApp(app, app.UIFigure)

            % Execute the startup function
            runStartupFcn(app, @startupFcn)

            if nargout == 0
                clear app
            end
        end

        % Code that executes before app deletion
        function delete(app)

            % Delete UIFigure when app is deleted
            delete(app.UIFigure)
        end
    end
end